classdef ReadSensorDataApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Menu                         matlab.ui.container.Menu
        SelectFolderMenu             matlab.ui.container.Menu
        SelectSensorButtonGroup      matlab.ui.container.ButtonGroup
        PressureButton_LPS22HH       matlab.ui.control.ToggleButton
        TemperatureButton_STTS751    matlab.ui.control.ToggleButton
        HumidityButton_HTS221        matlab.ui.control.ToggleButton
        XButton_IIS2DH               matlab.ui.control.ToggleButton
        YButton_IIS2MDC              matlab.ui.control.ToggleButton
        XButton_IIS3DWB              matlab.ui.control.ToggleButton
        IMP34DT05Button              matlab.ui.control.ToggleButton
        MP23ABS1Button               matlab.ui.control.ToggleButton
        AcousticLabel                matlab.ui.control.Label
        MagneticLabel                matlab.ui.control.Label
        InertialLabel                matlab.ui.control.Label
        EnvironmentalLabel           matlab.ui.control.Label
        TemperatureButton_LPS22HH    matlab.ui.control.ToggleButton
        LPS22HHLabel                 matlab.ui.control.Label
        STTS751Label                 matlab.ui.control.Label
        HTS221Label                  matlab.ui.control.Label
        TemperatureButton_HTS221     matlab.ui.control.ToggleButton
        IIS2DHLabel                  matlab.ui.control.Label
        YButton_IIS2DH               matlab.ui.control.ToggleButton
        ZButton_IIS2DH               matlab.ui.control.ToggleButton
        XcelLabel                    matlab.ui.control.Label
        ISM330DHCXLabel              matlab.ui.control.Label
        XButton_ISM330DHCX_Xl        matlab.ui.control.ToggleButton
        YButton_ISM330DHCX_Xl        matlab.ui.control.ToggleButton
        ZButton_ISM330DHCX_Xl        matlab.ui.control.ToggleButton
        XcelLabel_2                  matlab.ui.control.Label
        XButton_ISM330DHCX_Gy        matlab.ui.control.ToggleButton
        YButton_ISM330DHCX_Gy        matlab.ui.control.ToggleButton
        ZButton_ISM330DHCX_Gy        matlab.ui.control.ToggleButton
        GyroLabel                    matlab.ui.control.Label
        IIS3DWBLabel                 matlab.ui.control.Label
        XButton_IIS2MDC              matlab.ui.control.ToggleButton
        YButton_IIS3DWB              matlab.ui.control.ToggleButton
        ZButton_IIS3DWB              matlab.ui.control.ToggleButton
        XcelLabel_3                  matlab.ui.control.Label
        IIS2MDCLabel                 matlab.ui.control.Label
        ZButton_IIS2MDC              matlab.ui.control.ToggleButton
        MLC_ISM330DHCX               matlab.ui.control.ToggleButton
        UIAxes                       matlab.ui.control.UIAxes
        FrequencyAnalysisPanel       matlab.ui.container.Panel
        SpectrogramButton            matlab.ui.control.StateButton
        NFFTEditFieldLabel           matlab.ui.control.Label
        NFFTEditField                matlab.ui.control.NumericEditField
        WindowsizeEditFieldLabel     matlab.ui.control.Label
        WindowsizeEditField          matlab.ui.control.NumericEditField
        SampleoverlapEditFieldLabel  matlab.ui.control.Label
        SampleoverlapEditField       matlab.ui.control.NumericEditField
        StartupInfoPanel             matlab.ui.container.Panel
        toSelectFolderLabel          matlab.ui.control.Label
        MenuorCtrlOLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        STWINlog = struct('rawData',[0 0],'DeviceConfig','', 'sensorName', '','sensorNumber','', 'dataType', '', 'units', ''); % Description
        fpath = ''; % Description
        spectrumPlot = struct('nfft', 0, 'windowSize', 0, 'nOverlap', 0); % Description
        dataCurrent = struct('data', 0, 'json', 0); % Description
    end
    
    methods (Access = private)
        
        function sensorIsActive = isactive(app, sensor, sub)
            sensorIsActive = false;
            for se = 1 : app.STWINlog.nSensors
                if strcmp( app.STWINlog.configJSON.device.sensor(se).name, sensor )
                    for su = 1 : length( app.STWINlog.configJSON.device.sensor(se).sensorDescriptor.subSensorDescriptor )
                        if (app.STWINlog.configJSON.device.sensor(se).sensorStatus.subSensorStatus(su).isActive == 1) && strcmp( app.STWINlog.configJSON.device.sensor(se).sensorDescriptor.subSensorDescriptor(su).sensorType, sub )
                            sensorIsActive = true;
                        end
                    end
                end
            end
        end
        
        function refreshButtons(app)
            app.PressureButton_LPS22HH.Enable = app.isactive('LPS22HH', 'PRESS');
            app.TemperatureButton_LPS22HH.Enable = app.isactive('LPS22HH', 'TEMP');
            
            app.TemperatureButton_STTS751.Enable = app.isactive('STTS751', 'TEMP');
            
            app.HumidityButton_HTS221.Enable = app.isactive('HTS221', 'HUM');
            app.TemperatureButton_HTS221.Enable = app.isactive('HTS221', 'TEMP');
            
            app.XButton_IIS2DH.Enable = app.isactive('IIS2DH', 'ACC');
            app.YButton_IIS2DH.Enable = app.isactive('IIS2DH', 'ACC');
            app.ZButton_IIS2DH.Enable = app.isactive('IIS2DH', 'ACC');
            
            app.XButton_IIS2MDC.Enable = app.isactive('IIS2MDC', 'MAG');
            app.YButton_IIS2MDC.Enable = app.isactive('IIS2MDC', 'MAG');
            app.ZButton_IIS2MDC.Enable = app.isactive('IIS2MDC', 'MAG');
            
            app.XButton_IIS3DWB.Enable = app.isactive('IIS3DWB', 'ACC');
            app.YButton_IIS3DWB.Enable = app.isactive('IIS3DWB', 'ACC');
            app.ZButton_IIS3DWB.Enable = app.isactive('IIS3DWB', 'ACC');
            
            app.IMP34DT05Button.Enable = app.isactive('IMP34DT05', 'MIC');
            app.MP23ABS1Button.Enable = app.isactive('MP23ABS1', 'MIC');
            
            app.XButton_ISM330DHCX_Xl.Enable = app.isactive('ISM330DHCX', 'ACC');
            app.YButton_ISM330DHCX_Xl.Enable = app.isactive('ISM330DHCX', 'ACC');
            app.ZButton_ISM330DHCX_Xl.Enable = app.isactive('ISM330DHCX', 'ACC');
            
            app.XButton_ISM330DHCX_Gy.Enable = app.isactive('ISM330DHCX', 'GYRO');
            app.YButton_ISM330DHCX_Gy.Enable = app.isactive('ISM330DHCX', 'GYRO');
            app.ZButton_ISM330DHCX_Gy.Enable = app.isactive('ISM330DHCX', 'GYRO');
            
            app.MLC_ISM330DHCX.Enable = app.isactive('ISM330DHCX', 'MLC');
        end
        
        
        function results = plotdata(app, JSON, data, samples_time, subsensor, sub_idx, sub_axis)
            
            sensorName = JSON.name;
            kk = sub_axis;
            
            
            if app.SpectrogramButton.Value == 0
                data = cast(data,'single');
                plot(app.UIAxes, samples_time,data(kk,:), 'color',rand(1,3))
                %legend(app.UIAxes, dataType)
                ylabel(app.UIAxes, app.STWINlog.units)
                xlabel(app.UIAxes, 'sec')
                title(app.UIAxes, [sensorName ' - ' subsensor])
            else
                fs = JSON.sensorStatus.subSensorStatus(sub_idx).ODR;
                xx = cast(data(kk,:), 'single');
                
                nfft = app.spectrumPlot.nfft;
                w = app.spectrumPlot.windowSize;
                nOver = app.spectrumPlot.nOverlap;
                myAxe = app.UIAxes;
                [S,F,T] = spectrogram(xx,w,nOver,nfft,fs);
                imagesc(myAxe, T, F, log(1+abs(S)) ); %plot the log spectrum
                set(myAxe,'YDir', 'normal'); % flip the Y Axis so lower frequencies are at the bottom
                %                     legend(app.UIAxes, 'off')
                ylabel(app.UIAxes, ['frequency' app.STWINlog.units])
                xlabel(app.UIAxes, 'sec')
                title(app.UIAxes, [sensorName ' - ' subsensor])
            end
            results = 0;
            
        end
        
        
        function [units, sensor_idx, subSensor_idx] = get_subsensorInfo(app, cj, sensor, subSensor)
            % now find the index
            for se = 1:cj.device.deviceInfo.nSensor
                if strcmp( cj.device.sensor(se).name, sensor )
                    sensor_idx = se;
                    for su = 1:length( cj.device.sensor(se).sensorDescriptor.subSensorDescriptor )
                        if strcmp( cj.device.sensor(se).sensorDescriptor.subSensorDescriptor(su).sensorType, subSensor )
                            subSensor_idx = su;
                            units = cj.device.sensor(se).sensorDescriptor.subSensorDescriptor(su).unit;
                        end
                    end
                end
            end
        end
        
        function [log, s_time] = get_subsensorData(app, cj, filename, path)
            
            fileNameCell = strsplit(filename, {'_', '.'});
            sensor = fileNameCell{1};
            subSensor = fileNameCell{2};
            % now find the index
            for se = 1:cj.device.deviceInfo.nSensor
                if strcmp( cj.device.sensor(se).name, sensor )
                    sensor_idx = se;
                    for su = 1:length( cj.device.sensor(se).sensorDescriptor.subSensorDescriptor )
                        if strcmp( cj.device.sensor(se).sensorDescriptor.subSensorDescriptor(su).sensorType, subSensor )
                            subSensor_idx = su;
                        end
                    end
                end
            end
            
            fileDAT = strcat(path, filename);
            fid = fopen(fileDAT);
            rawdata = fread(fid,inf,'uint8=>uint8');
            fclose(fid);
            
            [log, s_time] = app.decodeRawdata(cj.device.sensor(sensor_idx), subSensor_idx, rawdata);
            
        end
        
        
        function [sData, samples_time] = decodeRawdata(app, cj, sub_idx, raw_data)
            
            subSensorType = cj.sensorDescriptor.subSensorDescriptor(sub_idx).sensorType;
            n_axes = cj.sensorDescriptor.subSensorDescriptor(sub_idx).dimensions;
            
            dataType_size = app.checkTypeLength(cj.sensorDescriptor.subSensorDescriptor(sub_idx).dataType);
            dataType = app.checkType(cj.sensorDescriptor.subSensorDescriptor(sub_idx).dataType);
            samplesPerTs = cj.sensorStatus.subSensorStatus(sub_idx).samplesPerTs;
            if samplesPerTs == 0
                timeStamp_size = 0;
                dataFrame_size = length(raw_data);
            else
                timeStamp_size = app.checkTypeLength('double');
                dataFrame_size = samplesPerTs*sum(n_axes)*dataType_size;
            end
            
            frame_size = dataFrame_size + timeStamp_size;
            
            if strcmp(subSensorType, 'MLC')
                framePeriod = 0;
                checkTimeStamps = 0;
            else
                framePeriod = samplesPerTs/cj.sensorStatus.subSensorStatus(sub_idx).ODR;
                checkTimeStamps = 1;
            end
            
            num_frames = floor(length(raw_data)/frame_size);
            
            if num_frames == 0
                warning('No valid data avilable for %s in sensor %s ', cj.sensorDescriptor.subSensorDescriptor(su).sensorType, cj.name);
            else
                rndDataBuffer = raw_data(1:(frame_size * num_frames));
                samplesPerFrame = dataFrame_size / dataType_size;
                if samplesPerTs == 0
                    samplesPerTs = samplesPerFrame;
                end
                
                data = zeros([num_frames*samplesPerFrame, 1],dataType);
                timeStamp = zeros([num_frames, 1],app.checkType('double'));
                
                for jj = 1:num_frames
                    startFrame = (jj-1)*frame_size+1;
                    segmentTS = rndDataBuffer(startFrame+dataFrame_size:startFrame+frame_size-1);
                    segmentData = rndDataBuffer(startFrame:startFrame+dataFrame_size-1);
                    if ~isempty(segmentTS)
                        timeStamp(jj) = typecast(segmentTS, app.checkType('double'));
                    end
                    data( (jj-1)*samplesPerFrame+1:(jj*samplesPerFrame) ) = typecast(segmentData, dataType);
                    
                    % Check Timestamp consistency
                    if (checkTimeStamps == 1 && jj > 1)
                        deltaTS = timeStamp(jj)-timeStamp(jj-1);
                        if abs(deltaTS)<0.66*framePeriod || abs(deltaTS)>1.33*framePeriod || isnan(timeStamp(jj))|| isnan(timeStamp(jj-1))
                            data( (jj-1)*samplesPerFrame+1:(jj*samplesPerFrame) ) = 0;
                            warning('WARNING Sensor %s: corrupted data at %s sec', cj.name, timeStamp(jj));
                            timeStamp(jj) = timeStamp(jj-1)+framePeriod;
                        end
                    end
                end
                
                timestamp_first = cj.sensorStatus.subSensorStatus(sub_idx).initialOffset;
                timeStamp = [timestamp_first; timeStamp];
                
                if num_frames == 1
                    timeStamp = [timeStamp; framePeriod];
                end
                
                sensitivity = cj.sensorStatus.subSensorStatus(sub_idx).sensitivity;
                sData = reshape(data, [n_axes, num_frames*samplesPerTs]);
                sData = cast(sData,'single') .* sensitivity;
                
                samples_time = zeros([1, num_frames*samplesPerTs]);
                for jj = 1:num_frames
                    a = linspace(timeStamp(jj),timeStamp(jj+1),samplesPerTs+1);
                    samples_time(((jj-1)*samplesPerTs+1):(jj*samplesPerTs)) = a(2:end);
                end
                
            end
            
        end
        
        function type = checkType(app,type)
            
            switch type
                case 'uint8_t'
                    type = 'uint8';
                case 'uint16_t'
                    type = 'uint16';
                case 'uint32_t'
                    type = 'uint32';
                case 'int8_t'
                    type = 'int8';
                case 'int16_t'
                    type = 'int16';
                case 'int32_t'
                    type = 'int32';
                case 'float'
                    type = 'single';
                case 'double'
                    type = 'double';
            end
            
        end
        
        function typeLength = checkTypeLength(app,type)
            
            switch type
                case {'uint8_t','int8_t'}
                    typeLength = 1;
                case {'uint16_t','int16_t'}
                    typeLength = 2;
                case {'uint32_t','int32_t'}
                    typeLength = 4;
                case 'float'
                    typeLength = 4;
                case 'double'
                    typeLength = 8;
            end
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SelectSensorButtonGroup.Visible = 0;
            app.FrequencyAnalysisPanel.Visible = 0;
            app.toSelectFolderLabel.Visible = 1;
        end

        % Callback function
        function SelectFolderButtonPushed(app, event)
            SelectFolderMenuSelected(app, event)
        end

        % Selection changed function: SelectSensorButtonGroup
        function SelectSensorButtonGroupSelectionChanged(app, event)
            selectedButton = app.SelectSensorButtonGroup.SelectedObject;
            if isequal(selectedButton, app.TemperatureButton_LPS22HH)
                app.STWINlog.sensorName = 'LPS22HH';
                app.STWINlog.subSensorAxis = 1;
                app.STWINlog.subSensorType = "TEMP";
            elseif isequal(selectedButton, app.PressureButton_LPS22HH)
                app.STWINlog.sensorName = 'LPS22HH';
                app.STWINlog.subSensorAxis = 1;
                app.STWINlog.subSensorType = "PRESS";
            elseif isequal(selectedButton, app.TemperatureButton_STTS751)
                app.STWINlog.sensorName = 'STTS751';
                app.STWINlog.subSensorAxis = 1;
                app.STWINlog.subSensorType = "TEMP";
            elseif isequal(selectedButton, app.TemperatureButton_HTS221)
                app.STWINlog.sensorName = 'HTS221';
                app.STWINlog.subSensorAxis = 1;
                app.STWINlog.subSensorType = "TEMP";
            elseif isequal(selectedButton, app.HumidityButton_HTS221)
                app.STWINlog.sensorName = 'HTS221';
                app.STWINlog.subSensorAxis = 1;
                app.STWINlog.subSensorType = "HUM";
            elseif isequal(selectedButton, app.XButton_IIS2DH)
                app.STWINlog.sensorName = 'IIS2DH';
                app.STWINlog.subSensorAxis = 1;
                app.STWINlog.subSensorType = "ACC";
            elseif isequal(selectedButton, app.YButton_IIS2DH)
                app.STWINlog.sensorName = 'IIS2DH';
                app.STWINlog.subSensorAxis = 2;
                app.STWINlog.subSensorType = "ACC";
            elseif isequal(selectedButton, app.ZButton_IIS2DH)
                app.STWINlog.sensorName = 'IIS2DH';
                app.STWINlog.subSensorAxis = 3;
                app.STWINlog.subSensorType = "ACC";
            elseif isequal(selectedButton, app.XButton_ISM330DHCX_Xl)
                app.STWINlog.sensorName = 'ISM330DHCX';
                app.STWINlog.subSensorAxis = 1;
                app.STWINlog.subSensorType = "ACC";
            elseif isequal(selectedButton, app.YButton_ISM330DHCX_Xl)
                app.STWINlog.sensorName = 'ISM330DHCX';
                app.STWINlog.subSensorAxis = 2;
                app.STWINlog.subSensorType = "ACC";
            elseif isequal(selectedButton, app.ZButton_ISM330DHCX_Xl)
                app.STWINlog.sensorName = 'ISM330DHCX';
                app.STWINlog.subSensorAxis = 3;
                app.STWINlog.subSensorType = "ACC";
            elseif isequal(selectedButton, app.XButton_ISM330DHCX_Gy)
                app.STWINlog.sensorName = 'ISM330DHCX';
                app.STWINlog.subSensorAxis = 1;
                app.STWINlog.subSensorType = "GYRO";
            elseif isequal(selectedButton, app.YButton_ISM330DHCX_Gy)
                app.STWINlog.sensorName = 'ISM330DHCX';
                app.STWINlog.subSensorAxis = 2;
                app.STWINlog.subSensorType = "GYRO";
            elseif isequal(selectedButton, app.ZButton_ISM330DHCX_Gy)
                app.STWINlog.sensorName = 'ISM330DHCX';
                app.STWINlog.subSensorAxis = 3;
                app.STWINlog.subSensorType = "GYRO";
            elseif isequal(selectedButton, app.MLC_ISM330DHCX)
                app.STWINlog.sensorName = 'ISM330DHCX';
                app.STWINlog.subSensorAxis = 1;
                app.STWINlog.subSensorType = "MLC";
            elseif isequal(selectedButton, app.XButton_IIS3DWB)
                app.STWINlog.sensorName = 'IIS3DWB';
                app.STWINlog.subSensorAxis = 1;
                app.STWINlog.subSensorType = "ACC";
            elseif isequal(selectedButton, app.YButton_IIS3DWB)
                app.STWINlog.sensorName = 'IIS3DWB';
                app.STWINlog.subSensorAxis = 2;
                app.STWINlog.subSensorType = "ACC";
            elseif isequal(selectedButton, app.ZButton_IIS3DWB)
                app.STWINlog.sensorName = 'IIS3DWB';
                app.STWINlog.subSensorAxis = 3;
                app.STWINlog.subSensorType = "ACC";
            elseif isequal(selectedButton, app.XButton_IIS2MDC)
                app.STWINlog.sensorName = 'IIS2MDC';
                app.STWINlog.subSensorAxis = 1;
                app.STWINlog.subSensorType = "MAG";
            elseif isequal(selectedButton, app.YButton_IIS2MDC)
                app.STWINlog.sensorName = 'IIS2MDC';
                app.STWINlog.subSensorAxis = 2;
                app.STWINlog.subSensorType = "MAG";
            elseif isequal(selectedButton, app.ZButton_IIS2MDC)
                app.STWINlog.sensorName = 'IIS2MDC';
                app.STWINlog.subSensorAxis = 3;
                app.STWINlog.subSensorType = "MAG";
            elseif isequal(selectedButton, app.IMP34DT05Button)
                app.STWINlog.sensorName = 'IMP34DT05';
                app.STWINlog.subSensorAxis = 1;
                app.STWINlog.subSensorType = "MIC";
            elseif isequal(selectedButton, app.MP23ABS1Button)
                app.STWINlog.sensorName = 'MP23ABS1';
                app.STWINlog.subSensorAxis = 1;
                app.STWINlog.subSensorType = "MIC";
            end
            
            fileDAT = strcat(app.STWINlog.sensorName, '_', app.STWINlog.subSensorType, '.dat');
            
            [app.STWINlog.units, app.STWINlog.sensorNumber, app.STWINlog.subSensorNumber] = app.get_subsensorInfo(app.STWINlog.configJSON, app.STWINlog.sensorName, app.STWINlog.subSensorType);
            [app.STWINlog.samples_data, app.STWINlog.samples_time] = app.get_subsensorData(app.STWINlog.configJSON, fileDAT, app.fpath);
            
            app.plotdata(app.STWINlog.configJSON.device.sensor(app.STWINlog.sensorNumber), app.STWINlog.samples_data, app.STWINlog.samples_time, app.STWINlog.subSensorType, app.STWINlog.subSensorNumber, app.STWINlog.subSensorAxis);
            
        end

        % Value changed function: NFFTEditField
        function NFFTEditFieldValueChanged(app, event)
            value = app.NFFTEditField.Value;
            app.spectrumPlot.nfft = value;
        end

        % Value changed function: WindowsizeEditField
        function WindowsizeEditFieldValueChanged(app, event)
            value = app.WindowsizeEditField.Value;
            app.spectrumPlot.windowSize = value;
        end

        % Value changed function: SampleoverlapEditField
        function SampleoverlapEditFieldValueChanged(app, event)
            value = app.SampleoverlapEditField.Value;
            app.spectrumPlot.nOverlap = value;
        end

        % Value changed function: SpectrogramButton
        function SpectrogramButtonValueChanged(app, event)
            app.spectrumPlot.nfft = app.NFFTEditField.Value;
            app.spectrumPlot.windowSize = app.WindowsizeEditField.Value;
            app.spectrumPlot.nOverlap = app.SampleoverlapEditField.Value;
            jj = app.STWINlog.sensorNumber;
            app.plotdata(app.STWINlog.configJSON.device.sensor(jj), app.STWINlog.samples_data, app.STWINlog.samples_time, app.STWINlog.subSensorType, app.STWINlog.subSensorNumber, app.STWINlog.subSensorAxis);
        end

        % Menu selected function: SelectFolderMenu
        function SelectFolderMenuSelected(app, event)
            app.StartupInfoPanel.Visible = 0;
            p = uigetdir;
            if p ~= 0
                app.fpath = [p,filesep];
                app.SelectSensorButtonGroup.Visible = 1;
                app.FrequencyAnalysisPanel.Visible = 1;
                listing = dir(p);
            end
            
            fileJSON = join([app.fpath, 'DeviceConfig.json']);
            fid = fopen(fileJSON);
            str = fread(fid,inf);
            fclose(fid);
            app.STWINlog.configJSON = jsondecode(char(str)');
            app.STWINlog.nSensors = app.STWINlog.configJSON.device.deviceInfo.nSensor;
            app.refreshButtons();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.SelectFolderMenu = uimenu(app.Menu);
            app.SelectFolderMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectFolderMenuSelected, true);
            app.SelectFolderMenu.Tooltip = {'Ctrl+O to Open the Select Folder menu'};
            app.SelectFolderMenu.Accelerator = 'O';
            app.SelectFolderMenu.Text = 'Select Folder';
            app.SelectSensorButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectSensorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectSensorButtonGroupSelectionChanged, true);
            app.SelectSensorButtonGroup.Title = 'Select Sensor';
            app.PressureButton_LPS22HH = uitogglebutton(app.SelectSensorButtonGroup);
            app.PressureButton_LPS22HH.Text = 'Pressure';
            app.PressureButton_LPS22HH.Value = true;
            app.TemperatureButton_STTS751 = uitogglebutton(app.SelectSensorButtonGroup);
            app.TemperatureButton_STTS751.Text = 'Temperature';
            app.HumidityButton_HTS221 = uitogglebutton(app.SelectSensorButtonGroup);
            app.HumidityButton_HTS221.Text = 'Humidity';
            app.XButton_IIS2DH = uitogglebutton(app.SelectSensorButtonGroup);
            app.XButton_IIS2DH.Text = 'X';
            app.YButton_IIS2MDC = uitogglebutton(app.SelectSensorButtonGroup);
            app.YButton_IIS2MDC.Text = 'Y';
            app.XButton_IIS3DWB = uitogglebutton(app.SelectSensorButtonGroup);
            app.XButton_IIS3DWB.Text = 'X';
            app.IMP34DT05Button = uitogglebutton(app.SelectSensorButtonGroup);
            app.IMP34DT05Button.Text = 'IMP34DT05';
            app.MP23ABS1Button = uitogglebutton(app.SelectSensorButtonGroup);
            app.MP23ABS1Button.Text = 'MP23ABS1';
            app.AcousticLabel = uilabel(app.SelectSensorButtonGroup);
            app.AcousticLabel.Text = 'Acoustic';
            app.MagneticLabel = uilabel(app.SelectSensorButtonGroup);
            app.MagneticLabel.Text = 'Magnetic';
            app.InertialLabel = uilabel(app.SelectSensorButtonGroup);
            app.InertialLabel.Text = 'Inertial';
            app.EnvironmentalLabel = uilabel(app.SelectSensorButtonGroup);
            app.EnvironmentalLabel.Text = 'Environmental';
            app.TemperatureButton_LPS22HH = uitogglebutton(app.SelectSensorButtonGroup);
            app.TemperatureButton_LPS22HH.Text = 'Temperature';
            app.LPS22HHLabel = uilabel(app.SelectSensorButtonGroup);
            app.LPS22HHLabel.Text = 'LPS22HH';
            app.STTS751Label = uilabel(app.SelectSensorButtonGroup);
            app.STTS751Label.Text = 'STTS751';
            app.HTS221Label = uilabel(app.SelectSensorButtonGroup);
            app.HTS221Label.Text = 'HTS221';
            app.TemperatureButton_HTS221 = uitogglebutton(app.SelectSensorButtonGroup);
            app.TemperatureButton_HTS221.Text = 'Temperature';
            app.IIS2DHLabel = uilabel(app.SelectSensorButtonGroup);
            app.IIS2DHLabel.Text = 'IIS2DH';
            app.YButton_IIS2DH = uitogglebutton(app.SelectSensorButtonGroup);
            app.YButton_IIS2DH.Text = 'Y';
            app.ZButton_IIS2DH = uitogglebutton(app.SelectSensorButtonGroup);
            app.ZButton_IIS2DH.Text = 'Z';
            app.XcelLabel = uilabel(app.SelectSensorButtonGroup);
            app.XcelLabel.Text = 'Xcel';
            app.ISM330DHCXLabel = uilabel(app.SelectSensorButtonGroup);
            app.ISM330DHCXLabel.Text = 'ISM330DHCX';
            app.XButton_ISM330DHCX_Xl = uitogglebutton(app.SelectSensorButtonGroup);
            app.XButton_ISM330DHCX_Xl.Text = 'X';
            app.YButton_ISM330DHCX_Xl = uitogglebutton(app.SelectSensorButtonGroup);
            app.YButton_ISM330DHCX_Xl.Text = 'Y';
            app.ZButton_ISM330DHCX_Xl = uitogglebutton(app.SelectSensorButtonGroup);
            app.ZButton_ISM330DHCX_Xl.Text = 'Z';
            app.XcelLabel_2 = uilabel(app.SelectSensorButtonGroup);
            app.XcelLabel_2.Text = 'Xcel';
            app.XButton_ISM330DHCX_Gy = uitogglebutton(app.SelectSensorButtonGroup);
            app.XButton_ISM330DHCX_Gy.Text = 'X';
            app.YButton_ISM330DHCX_Gy = uitogglebutton(app.SelectSensorButtonGroup);
            app.YButton_ISM330DHCX_Gy.Text = 'Y';
            app.ZButton_ISM330DHCX_Gy = uitogglebutton(app.SelectSensorButtonGroup);
            app.ZButton_ISM330DHCX_Gy.Text = 'Z';
            app.GyroLabel = uilabel(app.SelectSensorButtonGroup);
            app.GyroLabel.Text = 'Gyro';
            app.IIS3DWBLabel = uilabel(app.SelectSensorButtonGroup);
            app.IIS3DWBLabel.Text = 'IIS3DWB';
            app.XButton_IIS2MDC = uitogglebutton(app.SelectSensorButtonGroup);
            app.XButton_IIS2MDC.Text = 'X';
            app.YButton_IIS3DWB = uitogglebutton(app.SelectSensorButtonGroup);
            app.YButton_IIS3DWB.Text = 'Y';
            app.ZButton_IIS3DWB = uitogglebutton(app.SelectSensorButtonGroup);
            app.ZButton_IIS3DWB.Text = 'Z';
            app.XcelLabel_3 = uilabel(app.SelectSensorButtonGroup);
            app.XcelLabel_3.Text = 'Xcel';
            app.IIS2MDCLabel = uilabel(app.SelectSensorButtonGroup);
            app.IIS2MDCLabel.Text = 'IIS2MDC';
            app.ZButton_IIS2MDC = uitogglebutton(app.SelectSensorButtonGroup);
            app.ZButton_IIS2MDC.Text = 'Z';
            app.MLC_ISM330DHCX = uitogglebutton(app.SelectSensorButtonGroup);
            app.MLC_ISM330DHCX.Text = 'MLC';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.FrequencyAnalysisPanel = uipanel(app.UIFigure);
            app.FrequencyAnalysisPanel.Title = 'Frequency Analysis';
            app.SpectrogramButton = uibutton(app.FrequencyAnalysisPanel, 'state');
            app.SpectrogramButton.ValueChangedFcn = createCallbackFcn(app, @SpectrogramButtonValueChanged, true);
            app.SpectrogramButton.Text = 'Spectrogram';
            app.NFFTEditFieldLabel = uilabel(app.FrequencyAnalysisPanel);
            app.NFFTEditFieldLabel.Text = 'NFFT';
            app.NFFTEditField = uieditfield(app.FrequencyAnalysisPanel, 'numeric');
            app.NFFTEditField.Limits = [2 Inf];
            app.NFFTEditField.ValueChangedFcn = createCallbackFcn(app, @NFFTEditFieldValueChanged, true);
            app.NFFTEditField.Value = 2048;
            app.WindowsizeEditFieldLabel = uilabel(app.FrequencyAnalysisPanel);
            app.WindowsizeEditFieldLabel.Text = 'Window size';
            app.WindowsizeEditField = uieditfield(app.FrequencyAnalysisPanel, 'numeric');
            app.WindowsizeEditField.Limits = [0 Inf];
            app.WindowsizeEditField.ValueChangedFcn = createCallbackFcn(app, @WindowsizeEditFieldValueChanged, true);
            app.WindowsizeEditField.Value = 2048;
            app.SampleoverlapEditFieldLabel = uilabel(app.FrequencyAnalysisPanel);
            app.SampleoverlapEditFieldLabel.Text = 'Sample overlap';
            app.SampleoverlapEditField = uieditfield(app.FrequencyAnalysisPanel, 'numeric');
            app.SampleoverlapEditField.ValueChangedFcn = createCallbackFcn(app, @SampleoverlapEditFieldValueChanged, true);
            app.SampleoverlapEditField.Value = 256;
            app.StartupInfoPanel = uipanel(app.UIFigure);
            app.StartupInfoPanel.Title = 'Startup Info';
            app.toSelectFolderLabel = uilabel(app.StartupInfoPanel);
            app.toSelectFolderLabel.Text = 'to Select Folder';
            app.MenuorCtrlOLabel = uilabel(app.StartupInfoPanel);
            app.MenuorCtrlOLabel.Text = 'Menu or Ctrl+O';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ReadSensorDataApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end